classdef MicroArousalSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicroSelectionUIFigure  matlab.ui.Figure
        SelectaButtonLabel      matlab.ui.control.Label
        NREMSleepButton         matlab.ui.control.StateButton
        MicroArousalButton      matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        MAState double % 1 if microarousal. 0 if not
    end
    methods (Access = private)
        
        function buttonStateReset(app)
        global buttonState 
        buttonState = ~buttonState;     
        end
        function Arousal(app)
        global MArousal 
        MArousal= 1;     
        end
        function Sleep(app)
        global MArousal 
        MArousal= 0;     
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MAState = 0;
        end

        % Value changed function: MicroArousalButton
        function MicroArousalButtonValueChanged(app, event)
            app.MAState = 1;
            Arousal(app)
            buttonStateReset(app)
            delete(app)
        end

        % Value changed function: NREMSleepButton
        function NREMSleepButtonValueChanged(app, event)
            app.MAState = 0; 
            Sleep(app)
            buttonStateReset(app)
            delete(app)
        end

        % Window key press function: MicroSelectionUIFigure
        function MicroSelectionUIFigureWindowKeyPress(app, event)
            key = event.Key;
            if key == 1
                app.MAState = 1;
                Arousal(app)
            elseif key == 0 
                Sleep(app)
                app.MAState = 0;
            end
            buttonStateReset(app)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MicroSelectionUIFigure = uifigure('Visible', 'off');
            app.MicroSelectionUIFigure.Name = 'MicroSelection';
            app.MicroSelectionUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @MicroSelectionUIFigureWindowKeyPress, true);
            app.MicroArousalButton = uibutton(app.MicroSelectionUIFigure, 'state');
            app.MicroArousalButton.ValueChangedFcn = createCallbackFcn(app, @MicroArousalButtonValueChanged, true);
            app.MicroArousalButton.Text = 'MicroArousal';
            app.NREMSleepButton = uibutton(app.MicroSelectionUIFigure, 'state');
            app.NREMSleepButton.ValueChangedFcn = createCallbackFcn(app, @NREMSleepButtonValueChanged, true);
            app.NREMSleepButton.Text = 'NREM Sleep';
            app.SelectaButtonLabel = uilabel(app.MicroSelectionUIFigure);
            app.SelectaButtonLabel.Text = 'Select a Button';
            % Show the figure after all components are created
            app.MicroSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MicroArousalSelection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicroSelectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicroSelectionUIFigure)
        end
    end
end