classdef Select_SleepStage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectSleepStageUIFigure    matlab.ui.Figure
        SelectabuttonortypeinthekeyboardLabel  matlab.ui.control.Label
        REMButton                   matlab.ui.control.StateButton
        NREMButton                  matlab.ui.control.StateButton
        AwakeButton                 matlab.ui.control.StateButton
        SelectBehavioralStateLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.SelectSleepStageUIFigure,'onscreen');            
        end

        % Value changed function: REMButton
        function REMButtonValueChanged(app, event)
            
            global ButtonValue %#ok<TLEV>
            ButtonValue = 3;
            global buttonState %#ok<GVMIS>
            buttonState = 1;
            delete(app)            
        end

        % Value changed function: NREMButton
        function NREMButtonValueChanged(app, event)
       
            global ButtonValue %#ok<TLEV>
            ButtonValue = 2;
            global buttonState %#ok<GVMIS>
            buttonState = 1;
            delete(app)
        end

        % Value changed function: AwakeButton
        function AwakeButtonValueChanged(app, event)
         
            global ButtonValue %#ok<TLEV>
            ButtonValue = 1;
            global buttonState %#ok<GVMIS>
            buttonState = 1;
            delete(app)
        end

        % Window key press function: SelectSleepStageUIFigure
        function SelectSleepStageUIFigureWindowKeyPress(app, event)
            KeyValue = event.Character;
            if strcmp(KeyValue,'a') || strcmp(KeyValue,'A') || strcmp(KeyValue,'1')
                AwakeButtonValueChanged(app,event);
            elseif strcmp(KeyValue,'n') || strcmp(KeyValue,'N') || strcmp(KeyValue,'2')
                NREMButtonValueChanged(app,event);
            elseif strcmp(KeyValue,'r') || strcmp(KeyValue,'R') || strcmp(KeyValue,'3')
                REMButtonValueChanged(app,event);
            end
            global buttonState %#ok<GVMIS>
            buttonState = 1;
            delete(app)
        end

        % Close request function: SelectSleepStageUIFigure
        function SelectSleepStageUIFigureCloseRequest(app, event)
            
            global buttonState %#ok<GVMIS>
            if buttonState == 0
                buttonState = 1;
                delete(app) 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectSleepStageUIFigure = uifigure('Visible', 'off');
            app.SelectSleepStageUIFigure.Name = 'Select Sleep Stage';
            app.SelectSleepStageUIFigure.CloseRequestFcn = createCallbackFcn(app, @SelectSleepStageUIFigureCloseRequest, true);
            app.SelectSleepStageUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @SelectSleepStageUIFigureWindowKeyPress, true);
            app.SelectSleepStageUIFigure.Tag = 'figure1';
            app.SelectBehavioralStateLabel = uilabel(app.SelectSleepStageUIFigure);
            app.SelectBehavioralStateLabel.Text = 'Select Behavioral State';
            app.AwakeButton = uibutton(app.SelectSleepStageUIFigure, 'state');
            app.AwakeButton.ValueChangedFcn = createCallbackFcn(app, @AwakeButtonValueChanged, true);
            app.AwakeButton.Text = 'Awake';
            app.NREMButton = uibutton(app.SelectSleepStageUIFigure, 'state');
            app.NREMButton.ValueChangedFcn = createCallbackFcn(app, @NREMButtonValueChanged, true);
            app.NREMButton.Text = 'NREM';
            app.REMButton = uibutton(app.SelectSleepStageUIFigure, 'state');
            app.REMButton.ValueChangedFcn = createCallbackFcn(app, @REMButtonValueChanged, true);
            app.REMButton.Text = 'REM';
            app.SelectabuttonortypeinthekeyboardLabel = uilabel(app.SelectSleepStageUIFigure);
            app.SelectabuttonortypeinthekeyboardLabel.Text = 'Select a button or type in the keyboard';
            % Show the figure after all components are created
            app.SelectSleepStageUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Select_SleepStage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectSleepStageUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectSleepStageUIFigure)
        end
    end
end